classdef winTabGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        jsBackDoor                   matlab.ui.control.HTML
        btn_RemoveBorder             matlab.ui.control.Button
        btn_HideTabGroupLabel        matlab.ui.control.Button
        TabGroup3_Grid               matlab.ui.container.GridLayout
        TabGroup3_Menu               matlab.ui.container.GridLayout
        TabGroup3_Menu_Button2Grid   matlab.ui.container.GridLayout
        TabGroup3_Menu_Button2Icon   matlab.ui.control.Image
        TabGroup3_Menu_Button2Label  matlab.ui.control.Label
        TabGroup3_Menu_Button1Grid   matlab.ui.container.GridLayout
        TabGroup3_Menu_Button1Icon   matlab.ui.control.Image
        TabGroup3_Menu_Button1Label  matlab.ui.control.Label
        TabGroup3_Menu_Underline     matlab.ui.control.Image
        TabGroup3                    matlab.ui.container.TabGroup
        TabGroup3_Tab1               matlab.ui.container.Tab
        TabGroup3_Tab1Grid           matlab.ui.container.GridLayout
        TabGroup3_Tab1Image          matlab.ui.control.Image
        TabGroup3_Tab2               matlab.ui.container.Tab
        TabGroup3_Tab2Grid           matlab.ui.container.GridLayout
        TabGroup3_Tab2Image          matlab.ui.control.Image
        TabGroup2_Grid               matlab.ui.container.GridLayout
        TabGroup2_Menu               matlab.ui.container.GridLayout
        TabGroup2_Menu_Info          matlab.ui.control.Image
        TabGroup2_Menu_Button2       matlab.ui.control.StateButton
        TabGroup2_Menu_Button1       matlab.ui.control.StateButton
        TabGroup2                    matlab.ui.container.TabGroup
        TabGroup2_Tab1               matlab.ui.container.Tab
        TabGroup2_Tab1Grid           matlab.ui.container.GridLayout
        TabGroup2_Tab1Image          matlab.ui.control.Image
        TabGroup2_Tab2               matlab.ui.container.Tab
        TabGroup2_Tab2Grid           matlab.ui.container.GridLayout
        TabGroup2_Tab2Image          matlab.ui.control.Image
        TabGroup1                    matlab.ui.container.TabGroup
        TabGroup1_Tab1               matlab.ui.container.Tab
        TabGroup1_Tab1Grid           matlab.ui.container.GridLayout
        TabGroup1_Tab1Image          matlab.ui.control.Image
        TabGroup1_Tab2               matlab.ui.container.Tab
        TabGroup1_Tab2Grid           matlab.ui.container.GridLayout
        TabGroup1_Tab2Image          matlab.ui.control.Image
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = appUtil.jsBackDoorHTMLSource();
        end

        %-----------------------------------------------------------------%
        function UserDataInitialization(app)
            app.btn_HideTabGroupLabel.UserData      = false;
            app.btn_RemoveBorder.UserData           = 0;
            app.TabGroup3_Menu_Button1Icon.UserData = true;
            app.TabGroup3_Menu_Button2Icon.UserData = false;
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            appUtil.winPosition(app.UIFigure)
            jsBackDoor_Initialization(app)
            UserDataInitialization(app)

        end

        % Callback function: TabGroup2_Menu_Button1,
        % TabGroup2_Menu_Button2, 
        % ...and 1 other component
        function TabGroup2ControlButtonPushed(app, event)
            
            switch event.Source
                case {app.TabGroup2_Menu_Button1, app.TabGroup2_Menu_Button2}
                    clickedButton = event.Source;
        
                    if event.PreviousValue
                        clickedButton.Value = true;
                        return
                    end
                    
                    nonClickedButtons = findobj(app.TabGroup2_Menu, 'Type', 'uistatebutton', '-not', 'Tag', clickedButton.Tag);
                    set(nonClickedButtons, 'Value', 0)
                    
                    tabIndex = str2double(clickedButton.Tag);
                    app.TabGroup2.SelectedTab = app.TabGroup2.Children(tabIndex);

                case app.TabGroup2_Menu_Info
                    msg = ['TabGroup selection is programmatically controlled by ' ...
                           'a graphical menu.'];
                    uialert(app.UIFigure, msg, '', 'Icon', 'info')
            end
            
        end

        % Image clicked function: TabGroup3_Menu_Button1Icon, 
        % ...and 1 other component
        function TabGroup3ControlImageClicked(app, event)
            
            focus(app.jsBackDoor)

            clickedButton = event.Source;
            if clickedButton.UserData
                return
            end

            clickedButton.UserData = ~clickedButton.UserData;
            nonClickedButtons = findobj(app.TabGroup3_Menu, 'Type', 'uiimage', '-not', 'Tag', clickedButton.Tag);
            set(nonClickedButtons, 'UserData', false)

            tabIndex    = str2double(clickedButton.Tag);
            columnWidth = repmat({22}, 1, numel(app.TabGroup3_Menu.ColumnWidth));
            columnWidth(tabIndex) = {'1x'};

            app.TabGroup3_Menu.ColumnWidth   = columnWidth;
            app.TabGroup3_Menu_Underline.Layout.Column = tabIndex;
            app.TabGroup3.SelectedTab = app.TabGroup3.Children(tabIndex);

        end

        % Button pushed function: btn_HideTabGroupLabel, btn_RemoveBorder
        function RemoveBorderButtonPushed(app, event)
            
            switch event.Source
                case app.btn_HideTabGroupLabel
                    event.Source.UserData = ~event.Source.UserData;
                    if event.Source.UserData
                        app.TabGroup2.Layout.Row = [1 2];
                        app.TabGroup3.Layout.Row = [1 2];
                    else
                        app.TabGroup2.Layout.Row = 2;
                        app.TabGroup3.Layout.Row = 2;
                    end                    

                case app.btn_RemoveBorder
                    event.Source.UserData = mod(event.Source.UserData+1, 3);
                    switch event.Source.UserData
                        case 0
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup2, 'borderWidth', '1px', 'borderRadius', '0px')
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup3, 'borderWidth', '1px', 'borderRadius', '0px')
                        case 1
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup2, 'borderWidth', '0px', 'borderRadius', '0px')
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup3, 'borderWidth', '0px', 'borderRadius', '0px')
                        case 2                        
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup2, 'borderWidth', '1px', 'borderRadius', '10px')
                            ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup3, 'borderWidth', '1px', 'borderRadius', '10px')
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup1 = uitabgroup(app.GridLayout);
            app.TabGroup1_Tab1 = uitab(app.TabGroup1);
            app.TabGroup1_Tab1.Title = 'Gray image';
            app.TabGroup1_Tab1Grid = uigridlayout(app.TabGroup1_Tab1);
            app.TabGroup1_Tab1Image = uiimage(app.TabGroup1_Tab1Grid);
            app.TabGroup1_Tab1Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256.png');
            app.TabGroup1_Tab2 = uitab(app.TabGroup1);
            app.TabGroup1_Tab2.Title = 'Colored image';
            app.TabGroup1_Tab2Grid = uigridlayout(app.TabGroup1_Tab2);
            app.TabGroup1_Tab2Image = uiimage(app.TabGroup1_Tab2Grid);
            app.TabGroup1_Tab2Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256Colored.png');
            app.TabGroup2_Grid = uigridlayout(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.TabGroup2_Grid);
            app.TabGroup2_Tab1 = uitab(app.TabGroup2);
            app.TabGroup2_Tab1.Title = 'Gray image';
            app.TabGroup2_Tab1Grid = uigridlayout(app.TabGroup2_Tab1);
            app.TabGroup2_Tab1Image = uiimage(app.TabGroup2_Tab1Grid);
            app.TabGroup2_Tab1Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256.png');
            app.TabGroup2_Tab2 = uitab(app.TabGroup2);
            app.TabGroup2_Tab2.Title = 'Colored image';
            app.TabGroup2_Tab2Grid = uigridlayout(app.TabGroup2_Tab2);
            app.TabGroup2_Tab2Image = uiimage(app.TabGroup2_Tab2Grid);
            app.TabGroup2_Tab2Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256Colored.png');
            app.TabGroup2_Menu = uigridlayout(app.TabGroup2_Grid);
            app.TabGroup2_Menu_Button1 = uibutton(app.TabGroup2_Menu, 'state');
            app.TabGroup2_Menu_Button1.ValueChangedFcn = createCallbackFcn(app, @TabGroup2ControlButtonPushed, true);
            app.TabGroup2_Menu_Button1.Tag = '1';
            app.TabGroup2_Menu_Button1.Icon = fullfile(pathToMLAPP, 'icons', 'Density_256.png');
            app.TabGroup2_Menu_Button1.Text = '';
            app.TabGroup2_Menu_Button1.Value = true;
            app.TabGroup2_Menu_Button2 = uibutton(app.TabGroup2_Menu, 'state');
            app.TabGroup2_Menu_Button2.ValueChangedFcn = createCallbackFcn(app, @TabGroup2ControlButtonPushed, true);
            app.TabGroup2_Menu_Button2.Tag = '2';
            app.TabGroup2_Menu_Button2.Icon = fullfile(pathToMLAPP, 'icons', 'Density_256Colored.png');
            app.TabGroup2_Menu_Button2.Text = '';
            app.TabGroup2_Menu_Info = uiimage(app.TabGroup2_Menu);
            app.TabGroup2_Menu_Info.ImageClickedFcn = createCallbackFcn(app, @TabGroup2ControlButtonPushed, true);
            app.TabGroup2_Menu_Info.ImageSource = fullfile(pathToMLAPP, 'icons', 'Dots_32.png');
            app.TabGroup3_Grid = uigridlayout(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.TabGroup3_Grid);
            app.TabGroup3_Tab1 = uitab(app.TabGroup3);
            app.TabGroup3_Tab1.Title = 'Gray image';
            app.TabGroup3_Tab1Grid = uigridlayout(app.TabGroup3_Tab1);
            app.TabGroup3_Tab1Image = uiimage(app.TabGroup3_Tab1Grid);
            app.TabGroup3_Tab1Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256.png');
            app.TabGroup3_Tab2 = uitab(app.TabGroup3);
            app.TabGroup3_Tab2.Title = 'Colored image';
            app.TabGroup3_Tab2Grid = uigridlayout(app.TabGroup3_Tab2);
            app.TabGroup3_Tab2Image = uiimage(app.TabGroup3_Tab2Grid);
            app.TabGroup3_Tab2Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_256Colored.png');
            app.TabGroup3_Menu = uigridlayout(app.TabGroup3_Grid);
            app.TabGroup3_Menu_Underline = uiimage(app.TabGroup3_Menu);
            app.TabGroup3_Menu_Underline.ImageSource = fullfile(pathToMLAPP, 'icons', 'LineH.png');
            app.TabGroup3_Menu_Button1Grid = uigridlayout(app.TabGroup3_Menu);
            app.TabGroup3_Menu_Button1Label = uilabel(app.TabGroup3_Menu_Button1Grid);
            app.TabGroup3_Menu_Button1Label.Text = 'GRAY IMAGE';
            app.TabGroup3_Menu_Button1Icon = uiimage(app.TabGroup3_Menu_Button1Grid);
            app.TabGroup3_Menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @TabGroup3ControlImageClicked, true);
            app.TabGroup3_Menu_Button1Icon.Tag = '1';
            app.TabGroup3_Menu_Button1Icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_18.png');
            app.TabGroup3_Menu_Button2Grid = uigridlayout(app.TabGroup3_Menu);
            app.TabGroup3_Menu_Button2Label = uilabel(app.TabGroup3_Menu_Button2Grid);
            app.TabGroup3_Menu_Button2Label.Text = 'COLORED IMAGE';
            app.TabGroup3_Menu_Button2Icon = uiimage(app.TabGroup3_Menu_Button2Grid);
            app.TabGroup3_Menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @TabGroup3ControlImageClicked, true);
            app.TabGroup3_Menu_Button2Icon.Tag = '2';
            app.TabGroup3_Menu_Button2Icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'Density_18Colored.png');
            app.btn_HideTabGroupLabel = uibutton(app.GridLayout, 'push');
            app.btn_HideTabGroupLabel.ButtonPushedFcn = createCallbackFcn(app, @RemoveBorderButtonPushed, true);
            app.btn_HideTabGroupLabel.Text = {'Hide/Unhide '; 'TabGroup '; 'LabelPanel'};
            app.btn_RemoveBorder = uibutton(app.GridLayout, 'push');
            app.btn_RemoveBorder.ButtonPushedFcn = createCallbackFcn(app, @RemoveBorderButtonPushed, true);
            app.btn_RemoveBorder.Text = {'Customizing '; 'TabGroup border'; 'using JS'};
            app.jsBackDoor = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTabGroup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end