classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ModalDialogFormcreatedinJSPanel  matlab.ui.container.Panel
        ModalDialogFormcreatedinJSGrid  matlab.ui.container.GridLayout
        CustomizeparametersCheckBox     matlab.ui.control.CheckBox
        ModalDialogParametersPanel      matlab.ui.container.Panel
        ModalDialogParametersGrid       matlab.ui.container.GridLayout
        ValuefieldtypeDropDown          matlab.ui.control.DropDown
        ValuefieldtypeDropDownLabel     matlab.ui.control.Label
        ValuefieldlabelEditField        matlab.ui.control.EditField
        ValuefieldlabelEditFieldLabel   matlab.ui.control.Label
        KeyfieldlabelEditField          matlab.ui.control.EditField
        KeyfieldlabelEditFieldLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        jsBackDoor                      matlab.ui.control.HTML
        btn_uicredential                matlab.ui.control.Button
        MATLABBuiltinModalWindowsPanel  matlab.ui.container.Panel
        MATLABBuiltinModalWindowsGrid   matlab.ui.container.GridLayout
        btn_uiprogressdlg               matlab.ui.control.Button
        btn_uiconfirm                   matlab.ui.control.Button
        btn_uialert                     matlab.ui.control.Button
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function jsBackDoorListener(app, event)
            switch event.HTMLEventName
                case 'credentialDialog'
                    Username = event.HTMLEventData.login;
                    Password = event.HTMLEventData.password;
                    updateScreen(app, Username, Password)

                otherwise
                    error('Unexpected call')
            end
        end

        %-----------------------------------------------------------------%
        function updateScreen(app, Username, Password)
            if app.CustomizeparametersCheckBox.Value
                app.Label.Text = sprintf('You entered: "<b>%s</b>" and "<b>%s</b>', Username, Password);
            else
                app.Label.Text = sprintf('Wow!!!\nNow I know that your login is "<b>%s</b>" and your secret password is "<b>%s</b>".', Username, Password);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            appUtil.winPosition(app.UIFigure)
            
            app.jsBackDoor.HTMLSource = appUtil.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoorListener(app, evt);

        end

        % Button pushed function: btn_uialert, btn_uiconfirm, 
        % ...and 1 other component
        function ButtonPushed(app, event)
            
            switch event.Source
                case app.btn_uialert
                    uialert(app.UIFigure, 'MATLAB Built-in uialert', '', 'Icon', 'info')

                case app.btn_uiconfirm
                    uiconfirm(app.UIFigure, 'MATLAB Built-in uiconfirm', '', 'Icon', 'question');

                case app.btn_uiprogressdlg
                    d = uiprogressdlg(app.UIFigure, 'Message', 'MATLAB Built-in uiprogressdlg', 'Title', '', 'Indeterminate', 'on');
                    pause(3)
                    delete(d)
            end

        end

        % Value changed function: CustomizeparametersCheckBox
        function CustomizeparametersCheckBoxValueChanged(app, event)
            
            if app.CustomizeparametersCheckBox.Value
                app.KeyfieldlabelEditField.Enable   = "on";
                app.ValuefieldlabelEditField.Enable = "on";
                app.ValuefieldtypeDropDown.Enable   = "on";
            else
                app.KeyfieldlabelEditField.Enable   = "off";
                app.ValuefieldlabelEditField.Enable = "off";
                app.ValuefieldtypeDropDown.Enable   = "off";
            end
            
        end

        % Button pushed function: btn_uicredential
        function btn_uicredentialButtonPushed(app, event)
            
            modalDialogParameters = struct('UUID', char(matlab.lang.internal.uuid()));

            if app.CustomizeparametersCheckBox.Value
                modalDialogParameters.keyLabel   = app.KeyfieldlabelEditField.Value;
                modalDialogParameters.valueLabel = app.ValuefieldlabelEditField.Value;
                modalDialogParameters.valueType  = app.ValuefieldtypeDropDown.Value;
            end

            sendEventToHTMLSource(app.jsBackDoor, 'credentialDialog', modalDialogParameters)

        end

        % Value changed function: KeyfieldlabelEditField, 
        % ...and 1 other component
        function KeyfieldlabelEditFieldValueChanged(app, event)
            
            event.Source.Value = strtrim(event.Source.Value);
            if isempty(event.Source.Value)
                event.Source.Value = event.PreviousValue;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MATLABBuiltinModalWindowsPanel = uipanel(app.GridLayout);
            app.MATLABBuiltinModalWindowsPanel.Title = 'MATLAB Built-in Modal Windows';
            app.MATLABBuiltinModalWindowsGrid = uigridlayout(app.MATLABBuiltinModalWindowsPanel);
            app.btn_uialert = uibutton(app.MATLABBuiltinModalWindowsGrid, 'push');
            app.btn_uialert.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uialert.Text = 'uialert';
            app.btn_uiconfirm = uibutton(app.MATLABBuiltinModalWindowsGrid, 'push');
            app.btn_uiconfirm.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uiconfirm.Text = 'uiconfirm';
            app.btn_uiprogressdlg = uibutton(app.MATLABBuiltinModalWindowsGrid, 'push');
            app.btn_uiprogressdlg.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btn_uiprogressdlg.Text = 'uiprogressdlg';
            app.ModalDialogFormcreatedinJSPanel = uipanel(app.GridLayout);
            app.ModalDialogFormcreatedinJSPanel.Title = 'Modal Dialog Form created in JavaScript using uihtml';
            app.ModalDialogFormcreatedinJSGrid = uigridlayout(app.ModalDialogFormcreatedinJSPanel);
            app.btn_uicredential = uibutton(app.ModalDialogFormcreatedinJSGrid, 'push');
            app.btn_uicredential.ButtonPushedFcn = createCallbackFcn(app, @btn_uicredentialButtonPushed, true);
            app.btn_uicredential.Text = 'uicredential';
            app.jsBackDoor = uihtml(app.ModalDialogFormcreatedinJSGrid);
            app.Label = uilabel(app.ModalDialogFormcreatedinJSGrid);
            app.Label.Interpreter = 'html';
            app.Label.Text = {'Hi! '; 'I don''t know your login and password yet. :('};
            app.ModalDialogParametersPanel = uipanel(app.ModalDialogFormcreatedinJSGrid);
            app.ModalDialogParametersGrid = uigridlayout(app.ModalDialogParametersPanel);
            app.KeyfieldlabelEditFieldLabel = uilabel(app.ModalDialogParametersGrid);
            app.KeyfieldlabelEditFieldLabel.Interpreter = 'html';
            app.KeyfieldlabelEditFieldLabel.Text = {'Key field label:'; '<font style="color: gray; font-size: 10px;">Default value: "Login"</font>'};
            app.KeyfieldlabelEditField = uieditfield(app.ModalDialogParametersGrid, 'text');
            app.KeyfieldlabelEditField.ValueChangedFcn = createCallbackFcn(app, @KeyfieldlabelEditFieldValueChanged, true);
            app.KeyfieldlabelEditField.Enable = 'off';
            app.KeyfieldlabelEditField.Value = 'Login';
            app.ValuefieldlabelEditFieldLabel = uilabel(app.ModalDialogParametersGrid);
            app.ValuefieldlabelEditFieldLabel.Interpreter = 'html';
            app.ValuefieldlabelEditFieldLabel.Text = {'Value field label:'; '<font style="color: gray; font-size: 10px;">Default value: "Password"</font>'};
            app.ValuefieldlabelEditField = uieditfield(app.ModalDialogParametersGrid, 'text');
            app.ValuefieldlabelEditField.ValueChangedFcn = createCallbackFcn(app, @KeyfieldlabelEditFieldValueChanged, true);
            app.ValuefieldlabelEditField.Enable = 'off';
            app.ValuefieldlabelEditField.Value = 'Password';
            app.ValuefieldtypeDropDownLabel = uilabel(app.ModalDialogParametersGrid);
            app.ValuefieldtypeDropDownLabel.Interpreter = 'html';
            app.ValuefieldtypeDropDownLabel.Text = {'Key field type:'; '<font style="color: gray; font-size: 10px;">Default value: "password"</font>'};
            app.ValuefieldtypeDropDown = uidropdown(app.ModalDialogParametersGrid);
            app.ValuefieldtypeDropDown.Items = {'password', 'text'};
            app.ValuefieldtypeDropDown.Enable = 'off';
            app.ValuefieldtypeDropDown.Value = 'password';
            app.CustomizeparametersCheckBox = uicheckbox(app.ModalDialogFormcreatedinJSGrid);
            app.CustomizeparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomizeparametersCheckBoxValueChanged, true);
            app.CustomizeparametersCheckBox.Text = 'Customize parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end