classdef winWordCloud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        Search          matlab.ui.control.Image
        URL             matlab.ui.control.DropDown
        URLLabel        matlab.ui.control.Label
        nWords          matlab.ui.control.DropDown
        nWordsLabel     matlab.ui.control.Label
        Algorithm       matlab.ui.control.DropDown
        AlgorithmLabel  matlab.ui.control.Label
        Container       matlab.ui.container.Panel
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        wordCloudObj
        BagOfWords
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function loadCache(app)
            pathToMLAPP   = fileparts(mfilename('fullpath'));
            cacheFilePath = fullfile(pathToMLAPP, 'BagOfWords.mat');
            
            load(cacheFilePath, 'bag')
            app.BagOfWords = bag;
        end

        %-----------------------------------------------------------------%
        function updateWordCloud(app)
            if ~isempty(app.BagOfWords)    
                app.wordCloudObj.Table = topkwords(app.BagOfWords, str2double(app.nWords.Value));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            appUtil.winPosition(app.UIFigure)

            app.wordCloudObj = wordCloud(app.Container, app.Algorithm.Value);
            loadCache(app)
            updateWordCloud(app)

        end

        % Image clicked function: Search
        function SearchClicked(app, event)
            
            try
                webContent  = webread(app.URL.Value);

                webTree     = htmlTree(webContent);
                webSubTree  = findElement(webTree, 'A');            
                listOfWords = lower(extractHTMLText(webSubTree));

                documents   = tokenizedDocument(listOfWords);
                documents   = erasePunctuation(documents);
                documents   = removeStopWords(documents);
                documents   = removeShortWords(documents, 2);            
                bag         = bagOfWords(documents);

                app.BagOfWords = bag;

            catch ME
                loadCache(app)
                uialert(app.UIFigure, sprintf('%s\n\nShowing data from cache.', ME.message), '', 'Icon', 'error')
            end

            updateWordCloud(app)

        end

        % Value changed function: nWords
        function nWordsValueChanged(app, event)

            updateWordCloud(app)
            
        end

        % Value changed function: Algorithm
        function AlgorithmValueChanged(app, event)
            
            delete(app.wordCloudObj)
            drawnow nocallbacks
            pause(.100)

            app.wordCloudObj = wordCloud(app.Container, app.Algorithm.Value);
            updateWordCloud(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Container = uipanel(app.GridLayout);
            app.AlgorithmLabel = uilabel(app.GridLayout);
            app.AlgorithmLabel.Text = 'ALGORITHM:';
            app.Algorithm = uidropdown(app.GridLayout);
            app.Algorithm.Items = {'D3.js', 'MATLAB built-in'};
            app.Algorithm.ValueChangedFcn = createCallbackFcn(app, @AlgorithmValueChanged, true);
            app.Algorithm.Value = 'D3.js';
            app.nWordsLabel = uilabel(app.GridLayout);
            app.nWordsLabel.Text = '#WORDS:';
            app.nWords = uidropdown(app.GridLayout);
            app.nWords.Items = {'10', '50', '100'};
            app.nWords.ValueChangedFcn = createCallbackFcn(app, @nWordsValueChanged, true);
            app.nWords.Value = '50';
            app.URLLabel = uilabel(app.GridLayout);
            app.URLLabel.Text = 'URL:';
            app.URL = uidropdown(app.GridLayout);
            app.URL.Items = {'https://www.google.com/search?q=MATLAB', 'https://www.google.com/search?q=ANATEL'};
            app.URL.Editable = 'on';
            app.URL.Value = 'https://www.google.com/search?q=MATLAB';
            app.Search = uiimage(app.GridLayout);
            app.Search.ImageClickedFcn = createCallbackFcn(app, @SearchClicked, true);
            app.Search.ImageSource = fullfile(pathToMLAPP, 'icons', 'Zoom_36x36.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winWordCloud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end