classdef winSearchField < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        jsBackDoor           matlab.ui.control.HTML
        nMinCharacters       matlab.ui.control.Spinner
        nMinCharactersLabel  matlab.ui.control.Label
        Suggestions          matlab.ui.control.ListBox
        EntryPointPanel      matlab.ui.container.Panel
        EntryPointGrid       matlab.ui.container.GridLayout
        EntryPointImage      matlab.ui.control.Image
        EntryPoint           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        previousSearch   = ''
        previousItemData = 0
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = appUtil.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customization(app)
            ccTools.compCustomizationV2(app.jsBackDoor, app.EntryPoint, 'borderWidth', '0px')

            objList = {app.EntryPoint, app.Suggestions};
            for ii = 1:numel(objList)
                objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
            end
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('componentName', 'app.EntryPoint',  'componentDataTag', app.EntryPoint.UserData,  'keyEvents', ["ArrowUp", "ArrowDown", "Enter", "Escape", "Tab"]))
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('componentName', 'app.Suggestions', 'componentDataTag', app.Suggestions.UserData, 'keyEvents', ["ArrowUp", "ArrowDown", "Enter", "Escape", "Tab"]))
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            % The JS-keydown event for the keys ["ArrowUp", "ArrowDown", "Enter", "Escape"]
            % was added to the components app.EntryPoint (matlab.ui.control.EditField) and 
            % app.Suggestions (matlab.ui.control.ListBox) using the JS-backdoor app.jsBackDoor 
            % (matlab.ui.control.HTML).
            
            % Regarding the callbacks configurable directly in MATLAB:
            % - matlab.ui.control.EditField
            %   (a) Has the events "ValueChangedFcn" and "ValueChangingFcn".
            %   (b) Does not respond to the "Escape" key.
            %   (c) Responds to the "ArrowUp" and "ArrowDown" keys by controlling the cursor 
            %       position (start and end, respectively).
            %   (d) Responds to the "Enter" key by triggering "ValueChangingFcn" and 
            %       "ValueChangedFcn" in that order.
            
            % - matlab.ui.control.ListBox
            %   (a) Has the events "ValueChangedFcn", "ClickedFcn", and "DoubleClickedFcn".
            %   (b) Responds to the "ArrowUp" and "ArrowDown" keys, triggering "ValueChangedFcn", 
            %       as long as its "boundaries" are not selected (for example, value 1 and "ArrowUp",
            %       or value n and "ArrowDown").
            
            % When one of the keys ["ArrowUp", "ArrowDown", "Enter", "Escape"] is pressed,
            % the JS-keydown event is triggered before the default events of the
            % matlab.ui.control.EditField and matlab.ui.control.ListBox components.
            
            % This is good, but also bad because it adds extra complexity! :(
            
            % When I change the content of app.EntryPoint without changing its focus,
            % the "ValueChangingFcn" event is triggered. If I press the "Enter" key,
            % this function (jsBackDoor_Listener) is executed before updating the "Value"
            % property of app.EntryPoint.
            
            % Because of this, it is essential to insert waitfor(app.EntryPoint, 'Value').
            % This is achieved by shifting the focus from app.EntryPoint to app.jsBackDoor.
            % By doing so, MATLAB "executes" the following operation:
            % app.EntryPoint.Value = app.EntryPoint.ChangingValue

            switch event.HTMLEventName
                case 'app.EntryPoint'
                    focus(app.jsBackDoor)

                    switch event.HTMLEventData
                        case {'Escape', 'Tab'}
                            search_EntryPoint_CheckIfNeedsUpdate(app)
                            if numel(app.EntryPoint.Value) < app.nMinCharacters.Value
                                search_EntryPoint_InitialValue(app)
                            end

                            if strcmp(event.HTMLEventData, 'Tab') && app.EntryPointImage.Enable
                                focus(app.EntryPointImage)
                            end

                            pause(.050)
                            set(app.Suggestions, Visible=0, Value={})

                        otherwise
                            search_EntryPoint_CheckIfNeedsUpdate(app)
                            if numel(app.EntryPoint.Value) < app.nMinCharacters.Value
                                sendEventToHTMLSource(app.jsBackDoor, 'setFocus', struct('componentName', 'app.EntryPoint', 'componentDataTag', app.EntryPoint.UserData));

                            else
                                switch event.HTMLEventData
                                    case 'ArrowDown'
                                        app.previousItemData = 1;
            
                                        set(app.Suggestions, 'Visible', 1, 'Value', 1)
                                        scroll(app.Suggestions, "top")
                                        focus(app.Suggestions)
                    
                                    case 'ArrowUp'
                                        nMaxValues = numel(app.Suggestions.Items);

                                        app.previousItemData = nMaxValues;            
                                        set(app.Suggestions, 'Visible', 1, 'Value', nMaxValues)
                                        scroll(app.Suggestions, "bottom")
                                        focus(app.Suggestions)

                                    case 'Enter'                                      
                                        set(app.Suggestions, Visible=0, Value={})
                                end
                            end
                    end

                %---------------------------------------------------------%
                case 'app.Suggestions'
                    switch event.HTMLEventData
                        case 'ArrowDown'
                            nMaxValues = numel(app.Suggestions.Items);

                            if (app.previousItemData == nMaxValues) && (app.Suggestions.Value == nMaxValues)
                                app.previousItemData = 0;
                                
                                set(app.Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, 'setFocus', struct('componentName', 'app.EntryPoint', 'componentDataTag', app.EntryPoint.UserData));
                            else
                                if isnumeric(app.Suggestions.Value)
                                    app.previousItemData = app.Suggestions.Value;
                                else
                                    app.previousItemData = 0;
                                end
                            end
        
                        case 'ArrowUp'
                            if (app.previousItemData == 1) && (app.Suggestions.Value == 1)
                                app.previousItemData = 0;

                                set(app.Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, 'setFocus', struct('componentName', 'app.EntryPoint', 'componentDataTag', app.EntryPoint.UserData));
                            else
                                if isnumeric(app.Suggestions.Value)
                                    app.previousItemData = app.Suggestions.Value;
                                else
                                    app.previousItemData = 0;
                                end
                            end
        
                        case {'Enter', 'Tab'}
                            if isnumeric(app.Suggestions.Value)    
                                app.EntryPoint.Value = app.Suggestions.Items{app.Suggestions.Value};
                                sendEventToHTMLSource(app.jsBackDoor, 'setFocus', struct('componentName', 'app.EntryPoint', 'componentDataTag', app.EntryPoint.UserData));
                                set(app.Suggestions, Visible=0, Value={})
                            end

                        case 'Escape'
                            set(app.Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function search_EntryPoint_InitialValue(app)
            set(app.EntryPoint, 'FontColor', [.8,.8,.8], 'Value', 'What do you want to search?')
            app.EntryPointImage.Enable = 0;
        end

        %-----------------------------------------------------------------%
        function search_EntryPoint_CheckIfNeedsUpdate(app)
            % As mentioned in the comments of the "jsBackDoor_Listener" function, 
            % when I change the content of app.EntryPoint without changing its 
            % focus, the "ValueChangingFcn" event is triggered.

            % If I press the "Enter" key, the "jsBackDoor_Listener" function is 
            % executed before updating the "Value" property of app.EntryPoint.
            
            % Because of this, it is essential to insert WAITFOR. The issue is 
            % that, occasionally, MATLAB loses the exact moment when the "Value" 
            % property of app.EntryPoint is changed, causing the app to freeze.
            
            % It was observed that under normal conditions, WAITFOR takes between 
            % 25 and 50 milliseconds to update the mentioned property. As a result, 
            % WAITFOR was replaced by a LOOP+PAUSE.
            
            % waitfor(app.EntryPoint, 'Value')

            tWaitFor = tic;
            while toc(tWaitFor) < .050
                if strcmp(app.EntryPoint.Value, app.previousSearch)
                    break
                end
                pause(.010)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            appUtil.winPosition(app.UIFigure)
            app.Suggestions.ItemsData = 1:numel(app.Suggestions.Items);
            jsBackDoor_Initialization(app)
            drawnow

            jsBackDoor_Customization(app)
            

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            hitObject = struct(event).HitObject;
            switch hitObject
                case app.EntryPoint
                    switch app.EntryPoint.Value
                        case 'What do you want to search?'
                            set(app.EntryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if numel(app.EntryPoint.Value) >= app.nMinCharacters.Value
                                app.Suggestions.Visible = 1;
                            end
                    end

                case app.Suggestions
                    if isempty(app.Suggestions.Value)
                        waitfor(app.Suggestions, 'Value')
                    end

                    jsBackDoor_Listener(app, struct('HTMLEventName', 'app.Suggestions', 'HTMLEventData', 'Enter'))

                otherwise
                    set(app.Suggestions, Visible=0, Value={})
                    if isempty(app.EntryPoint.Value)
                        search_EntryPoint_InitialValue(app)
                    end
            end

        end

        % Value changing function: EntryPoint
        function EntryPointValueChanging(app, event)
            
            if numel(event.Value) >= app.nMinCharacters.Value
                app.Suggestions.Visible = 1;
            else
                app.Suggestions.Visible = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EntryPointPanel = uipanel(app.GridLayout);
            app.EntryPointGrid = uigridlayout(app.EntryPointPanel);
            app.EntryPoint = uieditfield(app.EntryPointGrid, 'text');
            app.EntryPoint.CharacterLimits = [0 128];
            app.EntryPoint.ValueChangingFcn = createCallbackFcn(app, @EntryPointValueChanging, true);
            app.EntryPoint.Tag = 'PROMPT';
            app.EntryPoint.Value = 'What do you want to search?';
            app.EntryPointImage = uiimage(app.EntryPointGrid);
            app.EntryPointImage.Enable = 'off';
            app.EntryPointImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'Zoom_36x36.png');
            app.Suggestions = uilistbox(app.GridLayout);
            app.Suggestions.Items = {'AI', 'ANATEL', 'Antenna', 'AppDesigner', 'Communication', 'Drive-test', 'Instrument Control Toolbox', 'Mask', 'Mathworks', 'MATLAB', 'Receiver', 'Signal Processing Toolbox', 'Simulink', 'Spectrum task', 'Transmitter', 'www.anatel.gov.br', 'www.mathworks.com'};
            app.Suggestions.Tag = 'CAIXA DE BUSCA';
            app.Suggestions.Visible = 'off';
            app.Suggestions.Value = {};
            app.nMinCharactersLabel = uilabel(app.GridLayout);
            app.nMinCharactersLabel.Text = 'Number minimum of characters:';
            app.nMinCharacters = uispinner(app.GridLayout);
            app.nMinCharacters.Limits = [2 4];
            app.nMinCharacters.RoundFractionalValues = 'on';
            app.nMinCharacters.ValueDisplayFormat = '%d';
            app.nMinCharacters.Value = 2;
            app.jsBackDoor = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSearchField

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end