classdef MainAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        OptionswindowmodeButtonGroup  matlab.ui.container.ButtonGroup
        UndockedButton                matlab.ui.control.RadioButton
        DockedButton                  matlab.ui.control.RadioButton
        ContainerClose                matlab.ui.control.Image
        OptionsButton                 matlab.ui.control.Button
        ContainerUndockImage          matlab.ui.control.Image
        ContainerPanel                matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = "Parula"; % Colormap 
    end

    methods (Access = public)
        %-----------------------------------------------------------------%    
        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;
            
            % Update plot 
            Z = peaks(sz);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,c);
        end
    end

    methods (Access = private)
        %-----------------------------------------------------------------%
        function openDialogApp(app, operationType)
            switch operationType
                case 'Dock'
                    hContainer = app.ContainerPanel;
                case 'Undock'
                    hContainer = [];
            end

            % Get sample size and colormap
            szvalue = app.CurrentSize;
            cvalue = app.CurrentColormap;
            
            % Open the options dialog and pass inputs
            % (the first argument is the container)
            app.DialogApp = DialogAppExample_exported(hContainer, app, szvalue, cvalue);
        end

        %-----------------------------------------------------------------%
        function openDialogLayout(app, operationType)
            switch operationType
                case 'on:DockMode'
                    app.OptionsButton.Enable = "off";
                    app.GridLayout.ColumnWidth(4:7) = {10,180,22,22};
                case 'on:UndockMode'
                    app.OptionsButton.Enable = "off";
                    app.GridLayout.ColumnWidth(4:7) = {0,0,0,0};
                case 'off'
                    app.OptionsButton.Enable = "on";
                    app.GridLayout.ColumnWidth(4:7) = {0,0,0,0};
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Call updateplot to display an initial plot
            openDialogLayout(app, 'off')
            updateplot(app, app.CurrentSize, app.CurrentColormap)

        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            
            % Delete both apps
            delete(app.DialogApp)
            delete(app)

        end

        % Callback function: ContainerClose, ContainerUndockImage, 
        % ...and 1 other component
        function ContainerImageClicked(app, event)
            
            switch event.Source
                case app.OptionsButton
                    switch app.OptionswindowmodeButtonGroup.SelectedObject
                        case app.DockedButton
                            openDialogApp(app, 'Dock')
                            openDialogLayout(app, 'on:DockMode')
                        case app.UndockedButton
                            openDialogApp(app, 'Undock')
                            openDialogLayout(app, 'on:UndockMode')
                    end                    

                case app.ContainerClose
                    delete(app.DialogApp)
                    app.DialogApp = [];
                    openDialogLayout(app, 'off')

                case app.ContainerUndockImage
                    ContainerImageClicked(app, struct('Source', app.ContainerClose))
                    openDialogApp(app, 'Undock')
                    openDialogLayout(app, 'on:UndockMode')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Display Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.ContainerPanel = uipanel(app.GridLayout);
            app.ContainerPanel.Title = 'Container';
            app.ContainerUndockImage = uiimage(app.GridLayout);
            app.ContainerUndockImage.ImageClickedFcn = createCallbackFcn(app, @ContainerImageClicked, true);
            app.ContainerUndockImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'Undock_18.png');
            app.OptionsButton = uibutton(app.GridLayout, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ContainerImageClicked, true);
            app.OptionsButton.Text = 'Options';
            app.ContainerClose = uiimage(app.GridLayout);
            app.ContainerClose.ImageClickedFcn = createCallbackFcn(app, @ContainerImageClicked, true);
            app.ContainerClose.ImageSource = fullfile(pathToMLAPP, 'icons', 'Delete_12SVG.svg');
            app.OptionswindowmodeButtonGroup = uibuttongroup(app.GridLayout);
            app.OptionswindowmodeButtonGroup.Title = 'Options window mode';
            app.DockedButton = uiradiobutton(app.OptionswindowmodeButtonGroup);
            app.DockedButton.Text = 'Docked';
            app.DockedButton.Value = true;
            app.UndockedButton = uiradiobutton(app.OptionswindowmodeButtonGroup);
            app.UndockedButton.Text = 'Undocked';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end